classdef appPromoter001 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        DatabasesTab                    matlab.ui.container.Tab
        PromoterfastaEditFieldLabel     matlab.ui.control.Label
        PromoterfastaEditField          matlab.ui.control.EditField
        FindPositiveButton              matlab.ui.control.Button
        FindNegativeButton              matlab.ui.control.Button
        NonpromoterfastaEditFieldLabel  matlab.ui.control.Label
        NonpromoterfastaEditField       matlab.ui.control.EditField
        NumberofsamplesPanel            matlab.ui.container.Panel
        PositiveEditFieldLabel          matlab.ui.control.Label
        PositiveEditField               matlab.ui.control.NumericEditField
        NegativeEditFieldLabel          matlab.ui.control.Label
        NegativeEditField               matlab.ui.control.NumericEditField
        SequencesConfigurationPanel     matlab.ui.container.Panel
        UpstreambpSpinner_2Label        matlab.ui.control.Label
        UpstreambpSpinner_2             matlab.ui.control.Spinner
        DownstreambpSpinner_2Label      matlab.ui.control.Label
        DownstreambpSpinner_2           matlab.ui.control.Spinner
        TSSPositionSpinner_2Label       matlab.ui.control.Label
        TSSPositionSpinner_2            matlab.ui.control.Spinner
        LoaddatabaseButton              matlab.ui.control.Button
        SequencesConfigurationTab       matlab.ui.container.Tab
        UpstreambpSpinnerLabel          matlab.ui.control.Label
        UpstreambpSpinner               matlab.ui.control.Spinner
        DownstreambpSpinnerLabel        matlab.ui.control.Label
        DownstreambpSpinner             matlab.ui.control.Spinner
        TSSPositionSpinnerLabel         matlab.ui.control.Label
        TSSPositionSpinner              matlab.ui.control.Spinner
        ExploreTab                      matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        NucleotidecompositionTab        matlab.ui.container.Tab
        PropertysignalTab               matlab.ui.container.Tab
        TrainTab                        matlab.ui.container.Tab
        ClassificationTab               matlab.ui.container.Tab
    end


    properties (Access = private)
        FastaPositive % Promoters fasta
        FastaNegative % Non promoters fasta
        
    end

    methods (Access = private)
            
    end


    methods (Access = private)

        % Value changed function: PromoterfastaEditField
        function PromoterfastaEditFieldValueChanged(app, event)
            value = app.PromoterfastaEditField.Value;
            try
                app.FastaPositive = fastaread(app.PromoterfastaEditField.Value);
            catch
            end
        end

        % Value changed function: NonpromoterfastaEditField
        function NonpromoterfastaEditFieldValueChanged(app, event)
            value = app.NonpromoterfastaEditField.Value;
            try
                app.FastaNegative = fastaread(app.NonpromoterfastaEditField.Value);
            catch
            end
        end

        % Button pushed function: FindPositiveButton
        function FindPositiveButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.fa', 'Select a fasta file');
            app.PromoterfastaEditField.Value = fullfile(pathname, filename);
            try
                disp(app.PromoterfastaEditField.Value);
                app.FastaPositive = fastaread(app.PromoterfastaEditField.Value);
                disp(app.FastaPositive);
            catch
            end
        end

        % Button pushed function: FindNegativeButton
        function FindNegativeButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.fa', 'Select a fasta file');
            app.NonpromoterfastaEditField.Value = fullfile(pathname, filename);
            try
                disp(app.NonpromoterfastaEditField.Value);
                app.FastaNegative = fastaread(app.NonpromoterfastaEditField.Value);
                disp(app.FastaNegative);
            catch
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.TabGroup2 = uitabgroup(app.SetupTab);
            app.DatabasesTab = uitab(app.TabGroup2);
            app.DatabasesTab.Title = 'Databases';
            app.PromoterfastaEditFieldLabel = uilabel(app.DatabasesTab);
            app.PromoterfastaEditFieldLabel.Text = 'Promoter fasta';
            app.PromoterfastaEditField = uieditfield(app.DatabasesTab, 'text');
            app.PromoterfastaEditField.ValueChangedFcn = createCallbackFcn(app, @PromoterfastaEditFieldValueChanged, true);
            app.FindPositiveButton = uibutton(app.DatabasesTab, 'push');
            app.FindPositiveButton.ButtonPushedFcn = createCallbackFcn(app, @FindPositiveButtonPushed, true);
            app.FindPositiveButton.Text = 'Find Positive';
            app.FindNegativeButton = uibutton(app.DatabasesTab, 'push');
            app.FindNegativeButton.ButtonPushedFcn = createCallbackFcn(app, @FindNegativeButtonPushed, true);
            app.FindNegativeButton.Text = 'Find Negative';
            app.NonpromoterfastaEditFieldLabel = uilabel(app.DatabasesTab);
            app.NonpromoterfastaEditFieldLabel.Text = 'Non promoter fasta';
            app.NonpromoterfastaEditField = uieditfield(app.DatabasesTab, 'text');
            app.NonpromoterfastaEditField.ValueChangedFcn = createCallbackFcn(app, @NonpromoterfastaEditFieldValueChanged, true);
            app.NumberofsamplesPanel = uipanel(app.DatabasesTab);
            app.NumberofsamplesPanel.Title = 'Number of samples';
            app.PositiveEditFieldLabel = uilabel(app.NumberofsamplesPanel);
            app.PositiveEditFieldLabel.Text = 'Positive';
            app.PositiveEditField = uieditfield(app.NumberofsamplesPanel, 'numeric');
            app.NegativeEditFieldLabel = uilabel(app.NumberofsamplesPanel);
            app.NegativeEditFieldLabel.Text = 'Negative';
            app.NegativeEditField = uieditfield(app.NumberofsamplesPanel, 'numeric');
            app.SequencesConfigurationPanel = uipanel(app.DatabasesTab);
            app.SequencesConfigurationPanel.Title = 'Sequences Configuration';
            app.UpstreambpSpinner_2Label = uilabel(app.SequencesConfigurationPanel);
            app.UpstreambpSpinner_2Label.Text = 'Upstream (bp)';
            app.UpstreambpSpinner_2 = uispinner(app.SequencesConfigurationPanel);
            app.DownstreambpSpinner_2Label = uilabel(app.SequencesConfigurationPanel);
            app.DownstreambpSpinner_2Label.Text = 'Downstream (bp)';
            app.DownstreambpSpinner_2 = uispinner(app.SequencesConfigurationPanel);
            app.TSSPositionSpinner_2Label = uilabel(app.SequencesConfigurationPanel);
            app.TSSPositionSpinner_2Label.Text = 'TSS Position';
            app.TSSPositionSpinner_2 = uispinner(app.SequencesConfigurationPanel);
            app.LoaddatabaseButton = uibutton(app.DatabasesTab, 'push');
            app.LoaddatabaseButton.Text = 'Load database';
            app.SequencesConfigurationTab = uitab(app.TabGroup2);
            app.SequencesConfigurationTab.Title = 'Sequences Configuration';
            app.UpstreambpSpinnerLabel = uilabel(app.SequencesConfigurationTab);
            app.UpstreambpSpinnerLabel.Text = 'Upstream (bp)';
            app.UpstreambpSpinner = uispinner(app.SequencesConfigurationTab);
            app.DownstreambpSpinnerLabel = uilabel(app.SequencesConfigurationTab);
            app.DownstreambpSpinnerLabel.Text = 'Downstream (bp)';
            app.DownstreambpSpinner = uispinner(app.SequencesConfigurationTab);
            app.TSSPositionSpinnerLabel = uilabel(app.SequencesConfigurationTab);
            app.TSSPositionSpinnerLabel.Text = 'TSS Position';
            app.TSSPositionSpinner = uispinner(app.SequencesConfigurationTab);
            app.ExploreTab = uitab(app.TabGroup);
            app.ExploreTab.Title = 'Explore';
            app.TabGroup3 = uitabgroup(app.ExploreTab);
            app.NucleotidecompositionTab = uitab(app.TabGroup3);
            app.NucleotidecompositionTab.Title = 'Nucleotide composition';
            app.PropertysignalTab = uitab(app.TabGroup3);
            app.PropertysignalTab.Title = 'Property signal';
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appPromoter001

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end